classdef MainRecDAQ2_2023 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainReqDAQUIFigure             matlab.ui.Figure
        AmpGain                        matlab.ui.control.NumericEditField
        AmpGainEditFieldLabel          matlab.ui.control.Label
        FileNum                        matlab.ui.control.EditField
        SavefilenumLabel_2             matlab.ui.control.Label
        SaveWS                         matlab.ui.control.Button
        FileName_prefix                matlab.ui.control.EditField
        SavefileprefixLabel            matlab.ui.control.Label
        CapturePostTrigDur             matlab.ui.control.NumericEditField
        CapturePostTrigDuraqtionsEditFieldLabel  matlab.ui.control.Label
        CapturePreTrigDur              matlab.ui.control.NumericEditField
        CapturePreTrigDuraqtionsEditFieldLabel  matlab.ui.control.Label
        TriggerSlope                   matlab.ui.control.NumericEditField
        TriggerSlopeVsEditFieldLabel   matlab.ui.control.Label
        TriggerLevel                   matlab.ui.control.NumericEditField
        TriggerLevelmVEditFieldLabel   matlab.ui.control.Label
        TriggerChannel                 matlab.ui.control.DropDown
        TriggerChannelDropDownLabel    matlab.ui.control.Label
        LiveYaxis                      matlab.ui.control.NumericEditField
        LiveYaxismVEditFieldLabel      matlab.ui.control.Label
        LiveDuration                   matlab.ui.control.NumericEditField
        LiveDurationsecEditFieldLabel  matlab.ui.control.Label
        SamplingRate                   matlab.ui.control.NumericEditField
        SamplingRateHzLabel            matlab.ui.control.Label
        CaptureButton                  matlab.ui.control.StateButton
        StartButton                    matlab.ui.control.Button
        PauseButton                    matlab.ui.control.Button
        UIAxes_captured                matlab.ui.control.UIAxes
        UIAxes_live                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        d_in %DAQ input channels
        capture % setting info of DAQ capturing DATA
        savedata
        savefname =[];

        TimeStampsFIFOBuffer
        DataFIFOBuffer
        trigActive
        trigMoment
    end
    
    methods (Access = private)
        
        function pauseDAQ(app)
            % stop DAQ and delete lisnters and the session
            
            if isvalid(app.d_in)
                if app.d_in.Running
                    stop(app.d_in)
                end
                
            end
            
        end

        %%%%%%%%%%%%%%%%%%


        % DATA CAPTURE EVENT
        function dataCapture(app, src, evt)
            c = app.capture;

            % Aquire data from daq
            [data, timestamps, ~] =...
                read(src, src.ScansAvailableFcnCount, "OutputFormat", "Matrix");

            data = data * app.AmpGain.Value;

            if timestamps(1)==0
                app.TimeStampsFIFOBuffer = [];
                app.DataFIFOBuffer = [];
                app.trigActive = false;
                app.trigMoment = [];
            end
            % Store continuous acquisition data in FIFO data buffers
            app.TimeStampsFIFOBuffer =...
                StoreDataInFIFO(app, app.TimeStampsFIFOBuffer, c.bufferSize, timestamps);

            app.DataFIFOBuffer =...
                StoreDataInFIFO(app, app.DataFIFOBuffer, c.bufferSize, data);
          
            % Update live plot
            samplesToPlot = min([round(c.plotTimeSpan * src.Rate), size(app.DataFIFOBuffer,1)]);
            firstPoint = size(app.DataFIFOBuffer,1) - samplesToPlot + 1;

            app.UIAxes_live.XLim =...
                [app.TimeStampsFIFOBuffer(firstPoint), app.TimeStampsFIFOBuffer(end)];
            
            %Live plot has one line for each acquisition channel
            plot(app.UIAxes_live, ...
                app.TimeStampsFIFOBuffer(firstPoint:end), app.DataFIFOBuffer(firstPoint:end, 1))

            ymax = app.LiveYaxis.Value;
            if ymax==0
                app.UIAxes_live.YLimMode = 'auto';
            else
                app.UIAxes_live.YLimMode = 'manual';
                app.UIAxes_live.YLim = [-ymax ymax];
            end
            
            %Get capture toggle button condition (1 or 0)
            captureRequested = app.CaptureButton.Value;
            
            if captureRequested && (~app.trigActive)
                %app.CaptureButton.BackgroundColor = 'y';
                
                trigConfig.Channel = 1; %sscanf(get(hGui.TrigChannel, 'String'), '%u');
                trigConfig.Level = app.TriggerLevel.Value;
                trigConfig.Slope = app.TriggerSlope.Value;
                
                [app.trigActive, app.trigMoment] = detectTrig(app, prevData, latestData, trigConfig);
                
            elseif captureRequested && app.trigActive && ((app.DataFIFOBuffer(end,1)-app.trigMoment) > c.TimeSpan)

                pretrigDataPoint = app.CapturePreTrigDur.Value * src.Rate;
                
                trigSampleIndex = find(app.DataFIFOBuffer >= app.trigMoment, 1, 'first') - pretrigDataPoint;
                lastSampleIndex = round(trigSampleIndex + c.TimeSpan * src.Rate);

                capturedData = [app.TimeStampsFIFOBuffer(trigSampleIndex:lastSampleIndex),...
                    app.DataFIFOBuffer(trigSampleIndex:lastSampleIndex)];
                
                app.trigActive = false;

                % Plot data
                plot(app.UIAxes_captured,...
                    capturedData(:,1), capturedData(:,2), 'Color', [0 0.4470 0.7410])
                app.UIAxes_captured.XLim = [capturedData(1,1), capturedData(end,1)];
                
                app.CaptureButton.BackgroundColor = 'g';
                app.CaptureButton.Text = 'Capture';
                app.CaptureButton.Value = 0;
                
                %save captured data in the base WS.
                varName = app.FileName_prefix.Value;
                varNum = app.FileNum.Value;
                
                %save captured data in the DISK (as a single .mat file)
                hdir = pwd;
                fname = [varName, '_', varNum, '.mat'];
                fname = fullfile(hdir,date, fname);
                disp(['save ', fname])
                save(fname, 'capturedData');
                app.savedata = capturedData;
                
            elseif captureRequested && app.trigActive && ((app.DataFIFOBuffer(end,1)-app.trigMoment) < c.TimeSpan)
                %Wait for sufficient number of data points are stored.
                app.CaptureButton.BackgroundColor = 'b';
                app.CaptureButton.Text = 'Recording';
                
            elseif ~captureRequested
                app.trigActive = false;
                app.CaptureButton.BackgroundColor = 'g';
                app.CaptureButton.Text = 'Capture';
                app.CaptureButton.Value = 0;
                
            end
            drawnow;
            
            
        end
        
        
        %% Detect triger event
        function [trigDetected, trigMoment] = detectTrig(~, prevData, latestData, trigConfig)
            %check signal level
            trigCondition1 = latestData(:, 1 + trigConfig.Channel) > trigConfig.Level;
            data = [prevData; latestData];
            
            %check slope
            dt = latestData(2,1)-latestData(1,1);
            slope = diff(data(:, 1+trigConfig.Channel))/dt;
            trigCondition2 = slope > trigConfig.Slope;
            
            if isempty(prevData)
                trigCondition2 = [false; trigCondition2];
            end
            
            %level and slope are used
            trigCondition = trigCondition1 & trigCondition2;
            
            trigDetected = any(trigCondition);
            trigMoment = [];
            if trigDetected
                % Find time moment when trigger condition has been met
                trigTimeStamps = latestData(trigCondition, 1);
                trigMoment = trigTimeStamps(1);
            end
        end

        %% Update capture setting
        function UpdateCaptureSetting(app)
            app.capture.plotTimeSpan = app.LiveDuration.Value;% Live Plot Duration

            app.capture.PreTrig = app.CapturePreTrigDur.Value;
            app.capture.PostTrig = app.CapturePostTrigDur.Value;

            % Captured data duration (after trigger detected)
            app.capture.TimeSpan = app.capture.PreTrig + app.capture.PostTrig;

            callbackTimeSpan = double(app.d_in.ScansAvailableFcnCount)/app.d_in.Rate;

            app.capture.bufferTimeSpan =...
                max([app.capture.plotTimeSpan, app.capture.TimeSpan*2, callbackTimeSpan*3]);

            app.capture.bufferSize = round(app.capture.bufferTimeSpan * app.d_in.Rate);
        end


        function data = StoreDataInFIFO(~, data, buffersize, datablock)
            %storeDataInFIFO Store continuous acquisition data in a FIFO data buffer
            % Storing data in a finite-size FIFO buffer is used to plot the
            % latest "N" seconds of acquired data for a smooth live plot.
            %
            % The most recently acquired data ("datablock") is added to the buffer
            % If the amount of data in the buffer exceeds the specified buffer size
            % ("buffersize") the oldest data is discarded to cap the size of
            % the data in the buffer to buffersize.
            %
            % INPUT
            % (app): Do not use app.
            % data:         existing data buffer (column vector Nx(# of channels))
            % buffersize:   desired buffer size (maximum number of rows in data buffer)
            % datablock:    a new data block to be added to the buffer (column vector Kx(#Channels)).
            %
            % OUTPUT
            % data:         updated data buffer (column vector Mx1).
            if size(data,1) > buffersize
                data = data(end-buffersize+1:end, :);
            end

            if size(datablock,1) < buffersize
                % Data block size (number of rows) is smaller than the buffer size
                if size(data,1) == buffersize
                    % Current data size is already equal to buffer size.
                    % Discard older data and append new data block,
                    % and keep data size equal to buffer size.
                    % example)
                    % current data: [1,1,1,0,0,0,0,0], new data: [2,2,2]
                    % updated data: [2,2,2,1,1,1,0,0] -> discard [0,0,0]
                    shiftPosition = size(datablock,1);
                    data = circshift(data,-shiftPosition);
                    data(end-shiftPosition+1:end,:) = datablock;


                elseif (size(data,1) < buffersize) &&...
                        (size(data,1)+size(datablock,1) > buffersize)
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size greater than the buffer size.
                    data = [data; datablock];
                    shiftPosition = size(data,1) - buffersize;
                    data = circshift(data,-shiftPosition);
                    data(buffersize+1:end, :) = [];

                else
                    % Current data size is less than buffer size and appending the new
                    % data block results in a size smaller than or equal to the buffer size.
                    % size(data,1) < buffersize
                    % size(data,1)+size(datablock,1) <= buffersize
                    data = [data; datablock];
                end
            else
                % Data block size (number of rows) is larger than or equal to buffer size
                data = datablock(end-buffersize+1:end,:);
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %DAQ setting
            %Create a DAQ interface
            dlist = daqlist;
            DevNum = 0;
            for i = 1:size(dlist,1)
                if contains(dlist.Description(i), "6009")
                    DevNum = i;
                    break
                end
            end

            if DevNum == 0
                errordlg("NI-USB-6009 not found!")
            end
            %Find device

            daqreset;
            app.d_in = daq('ni');
            app.d_in.Rate = app.SamplingRate.Value;

            DevN = sprintf("Dev%d", i);
            addinput(app.d_in, DevN, "ai0", "Voltage") % Differential

            app.d_in.Channels(1).Range = [-5.0, 5.0];
            %app.d_in.Channels(2).Range = [-5.0, 5.0];

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            UpdateCaptureSetting(app);

        end

        % Close request function: MainReqDAQUIFigure
        function MainReqDAQUIFigureCloseRequest(app, event)
            pauseDAQ(app)
            delete(app)
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            pauseDAQ(app)

            app.StartButton.Enable="on";
            app.PauseButton.Enable="off";
            
        end

        % Value changed function: CaptureButton
        function CaptureButtonValueChanged(app, event)
            value = app.CaptureButton.Value;
            
            %make folder for save data
            hdir = pwd;
            spath = fullfile(hdir, date);
            if ~exist(spath, 'dir')
                mkdir(fullfile(hdir, date))
            end
            
            if exist(app.savefname, 'file')
                fname = app.savefname;
                app.CaptureButton.Value = 0;
                app.savefname = [];
                app.CaptureButton.Text ='Captuer';
                app.CaptureButton.BackgroundColor = 'g';
                
                app.FileNum.Value = '*****';
                
                errordlg(['File Name Invlaid:: ', fname, ' is already exist.']);
                
            end
                
            if ~isempty(app.savefname)
                if value == 1
                    app.CaptureButton.Text ='Wait trigger';
                    app.CaptureButton.BackgroundColor = 'y';
                    disp('Start wait trigger...')
                else
                    app.CaptureButton.Value = 0;
                    app.CaptureButton.Text ='Captuer';
                    app.CaptureButton.BackgroundColor = 'g';
                    disp('Stop wait trigger...')
                end
            else
                app.CaptureButton.Value = 0;
                app.savefname = [];
                app.CaptureButton.Text ='Captuer';
                app.CaptureButton.BackgroundColor = 'g';
                disp('Stop wait trigger...')
                
                errordlg('Put File number')
            end
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.d_in.Running == false
                app.StartButton.Enable="off";
                app.PauseButton.Enable="on";
                
                app.d_in.Rate = app.SamplingRate.Value;

                UpdateCaptureSetting(app);
                
                % Restart
                app.d_in.ScansAvailableFcn = @(src, evt) dataCapture(app, src, evt);
                start(app.d_in, "continuous");
                
                while app.d_in.Running
                    pause(0.5)
                end
                
                
            end
        end

        % Button pushed function: SaveWS
        function SaveWSButtonPushed(app, event)
            
            assignin('base', 'capture', app.capture);
            if isfield(app, 'savedata')
                
                assignin('base', 'capturedData', app.savedata);
            else
                disp('No DATA available...')
                
            end
        end

        % Value changed function: FileNum
        function FileNumValueChanged(app, event)
            varName = app.FileName_prefix.Value;
            varNum = app.FileNum.Value;
            hdir = pwd;
            fname = [varName, '_', varNum, '.mat'];
            fname = fullfile(hdir, date, fname);
            if exist(fname, 'file')
                errordlg(['File Name Invlaid:: ', fname, ' is already exist.']);
                
                app.FileNum.Value = '*****';
                app.savefname = [];
            else
                app.savefname = fname;
            end
            
            disp(app.savefname)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainReqDAQUIFigure = uifigure('Visible', 'off');
            app.MainReqDAQUIFigure.Name = 'MainReqDAQ';
            app.MainReqDAQUIFigure.CloseRequestFcn = createCallbackFcn(app, @MainReqDAQUIFigureCloseRequest, true);
            app.UIAxes_live = uiaxes(app.MainReqDAQUIFigure);
            title(app.UIAxes_live, 'Live Plot')
            xlabel(app.UIAxes_live, 'Time (s)')
            ylabel(app.UIAxes_live, 'Voltage (mV)')
            app.UIAxes_live.XLim = [-Inf Inf];
            app.UIAxes_live.YLim = [-Inf Inf];
            app.UIAxes_captured = uiaxes(app.MainReqDAQUIFigure);
            title(app.UIAxes_captured, 'Capture Data')
            xlabel(app.UIAxes_captured, 'Time (s)')
            ylabel(app.UIAxes_captured, 'Voltage (mV)')
            app.UIAxes_captured.XLim = [-Inf Inf];
            app.UIAxes_captured.YLim = [-Inf Inf];
            app.PauseButton = uibutton(app.MainReqDAQUIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = 'Pause';
            app.StartButton = uibutton(app.MainReqDAQUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.CaptureButton = uibutton(app.MainReqDAQUIFigure, 'state');
            app.CaptureButton.ValueChangedFcn = createCallbackFcn(app, @CaptureButtonValueChanged, true);
            app.CaptureButton.Text = 'Capture';
            app.SamplingRateHzLabel = uilabel(app.MainReqDAQUIFigure);
            app.SamplingRateHzLabel.Text = 'Sampling Rate (Hz):';
            app.SamplingRate = uieditfield(app.MainReqDAQUIFigure, 'numeric');
            app.SamplingRate.ValueDisplayFormat = '%.0f';
            app.SamplingRate.Value = 15000;
            app.LiveDurationsecEditFieldLabel = uilabel(app.MainReqDAQUIFigure);
            app.LiveDurationsecEditFieldLabel.Text = 'Live Duration (sec):';
            app.LiveDuration = uieditfield(app.MainReqDAQUIFigure, 'numeric');
            app.LiveDuration.ValueDisplayFormat = '%.2f';
            app.LiveDuration.Value = 2;
            app.LiveYaxismVEditFieldLabel = uilabel(app.MainReqDAQUIFigure);
            app.LiveYaxismVEditFieldLabel.Text = 'Live Y-axis (mV):';
            app.LiveYaxis = uieditfield(app.MainReqDAQUIFigure, 'numeric');
            app.LiveYaxis.ValueDisplayFormat = '%.2f';
            app.TriggerChannelDropDownLabel = uilabel(app.MainReqDAQUIFigure);
            app.TriggerChannelDropDownLabel.Text = 'Trigger Channel:';
            app.TriggerChannel = uidropdown(app.MainReqDAQUIFigure);
            app.TriggerChannel.Items = {'Ch(1)', 'Ch(2)', 'Ch(3)', 'Ch(4)'};
            app.TriggerChannel.Value = 'Ch(1)';
            app.TriggerLevelmVEditFieldLabel = uilabel(app.MainReqDAQUIFigure);
            app.TriggerLevelmVEditFieldLabel.Text = 'Trigger Level (mV):';
            app.TriggerLevel = uieditfield(app.MainReqDAQUIFigure, 'numeric');
            app.TriggerLevel.ValueDisplayFormat = '%.0f';
            app.TriggerLevel.Value = 800;
            app.TriggerSlopeVsEditFieldLabel = uilabel(app.MainReqDAQUIFigure);
            app.TriggerSlopeVsEditFieldLabel.Text = 'Trigger Slope (V/s)';
            app.TriggerSlope = uieditfield(app.MainReqDAQUIFigure, 'numeric');
            app.TriggerSlope.ValueDisplayFormat = '%.0f';
            app.TriggerSlope.Editable = 'off';
            app.TriggerSlope.Value = 200;
            app.CapturePreTrigDuraqtionsEditFieldLabel = uilabel(app.MainReqDAQUIFigure);
            app.CapturePreTrigDuraqtionsEditFieldLabel.Text = 'Capture Pre-Trig Duraqtion (s)';
            app.CapturePreTrigDur = uieditfield(app.MainReqDAQUIFigure, 'numeric');
            app.CapturePreTrigDur.ValueDisplayFormat = '%.0f';
            app.CapturePreTrigDur.Value = 1;
            app.CapturePostTrigDuraqtionsEditFieldLabel = uilabel(app.MainReqDAQUIFigure);
            app.CapturePostTrigDuraqtionsEditFieldLabel.Text = 'Capture Post-Trig Duraqtion (s)';
            app.CapturePostTrigDur = uieditfield(app.MainReqDAQUIFigure, 'numeric');
            app.CapturePostTrigDur.ValueDisplayFormat = '%.0f';
            app.CapturePostTrigDur.Value = 10;
            app.SavefileprefixLabel = uilabel(app.MainReqDAQUIFigure);
            app.SavefileprefixLabel.Text = 'Save file prefix:';
            app.FileName_prefix = uieditfield(app.MainReqDAQUIFigure, 'text');
            app.FileName_prefix.Value = 'Group_X';
            app.SaveWS = uibutton(app.MainReqDAQUIFigure, 'push');
            app.SaveWS.ButtonPushedFcn = createCallbackFcn(app, @SaveWSButtonPushed, true);
            app.SaveWS.Text = 'Save capture data into  work space';
            app.SavefilenumLabel_2 = uilabel(app.MainReqDAQUIFigure);
            app.SavefilenumLabel_2.Text = 'Save file num: ';
            app.FileNum = uieditfield(app.MainReqDAQUIFigure, 'text');
            app.FileNum.ValueChangedFcn = createCallbackFcn(app, @FileNumValueChanged, true);
            app.FileNum.Value = '*****';
            app.AmpGainEditFieldLabel = uilabel(app.MainReqDAQUIFigure);
            app.AmpGainEditFieldLabel.Text = 'Amp Gain:';
            app.AmpGain = uieditfield(app.MainReqDAQUIFigure, 'numeric');
            app.AmpGain.ValueDisplayFormat = '%.0f';
            app.AmpGain.Editable = 'off';
            app.AmpGain.Value = 1000;
            % Show the figure after all components are created
            app.MainReqDAQUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainRecDAQ2_2023

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainReqDAQUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainReqDAQUIFigure)
        end
    end
end